classdef CNC_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TestLabel                     matlab.ui.control.Label
        uploadagcodefileLabel         matlab.ui.control.Label
        TextcontrolEditField          matlab.ui.control.EditField
        TextcontrolEditFieldLabel     matlab.ui.control.Label
        GcodefileforautoButton        matlab.ui.control.Button
        ModesDropDown                 matlab.ui.control.DropDown
        ModesDropDownLabel            matlab.ui.control.Label
        ManualmodecontrolLabel        matlab.ui.control.Label
        StepmodeDropDown              matlab.ui.control.DropDown
        StepmodeDropDownLabel         matlab.ui.control.Label
        SpindlemotorSlider            matlab.ui.control.Slider
        SpindlemotorSliderLabel       matlab.ui.control.Label
        KeycontrolsforxyzmotorsLabel  matlab.ui.control.Label
        QButton                       matlab.ui.control.Button
        AButton                       matlab.ui.control.Button
        EButton                       matlab.ui.control.Button
        DButton                       matlab.ui.control.Button
        SButton                       matlab.ui.control.Button
        WButton                       matlab.ui.control.Button
    end

    
    properties (Access = private)

        pico; % variable to store serial connection
        timer_joy; 

        %% --- key tracking variables ---
        key_w = false;
        key_s = false;
        key_d = false;
        key_a = false;
        key_e = false;
        key_q = false;
    end
    %% --- function to move the motors with key presses ---
    methods (Access = private)
        
        function process_keys(app)
            % writes the movement intructions to the pico
            if app.key_w
                app.positive_move_y();
            end
            if app.key_s
                app.negative_move_y();
            end
            if app.key_d
                app.positive_move_x();
            end
            if app.key_a
                app.negative_move_x();
            end
            if app.key_e
                app.positive_move_z();
            end
            if app.key_q
                app.negative_move_z();
            end
        end
        
        function move_y_positive(app)
            writeline(app.pico, "w");
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function pico_connection(app)
            app.pico = serialport("COM5", 115200);

            % relative mode so keys can move the motors
            writeline(app.pico, "G91");

            app.timer_joy = timer('ExecutionMode', 'fixedSpacing', 'Period', 0.02, 'TimerFcn', @(~,~) app.process_keys());

            start(app.timer_joy);
        end

        % Button pushed function: WButton
        function positive_move_y(app, event)
             move_y_positive(); % function call to send command
        end

        % Button pushed function: SButton
        function negative_move_y(app, event)
            writeline(app.pico, "s");
        end

        % Button pushed function: DButton
        function positive_move_x(app, event)
            writeline(app.pico, "d");
        end

        % Button pushed function: AButton
        function negative_move_x(app, event)
            writeline(app.pico, "a");
        end

        % Button pushed function: EButton
        function positive_move_z(app, event)
            writeline(app.pico, "e");
        end

        % Button pushed function: QButton
        function negative_move_z(app, event)
            writeline(app.pico, "q");
        end

        % Key press function: UIFigure
        function key_press(app, event)
            if app.ModesDropDown.Value ~= "Manual control"
                app.TestLabel.Text = "Switch to manual mode";
                return;
            end
            key = event.Key;
            switch key
                case 'w'
                    app.key_w = true;
                    app.WButton.BackgroundColor = [0 1 0];
                case 's'
                    app.key_s = true;
                    app.SButton.BackgroundColor = [0 1 0];
                case 'd'
                    app.key_d = true;
                    app.DButton.BackgroundColor = [0 1 0];
                case 'a'
                    app.key_a = true;
                    app.AButton.BackgroundColor = [0 1 0];
                case 'e'
                    app.key_e = true;
                    app.EButton.BackgroundColor = [0 1 0];
                case 'q'
                    app.key_q = true;
                    app.QButton.BackgroundColor = [0 1 0];
            end
        end

        % Key release function: UIFigure
        function key_release(app, event)
            key = event.Key;
            switch key
                case 'w'
                    app.key_w = false;
                    app.WButton.BackgroundColor = [0.94 0.94 0.94];
                case 's'
                    app.key_s = false;
                    app.SButton.BackgroundColor = [0.94 0.94 0.94];
                case 'd'
                    app.key_d = false;
                    app.DButton.BackgroundColor = [0.94 0.94 0.94];
                case 'a'
                    app.key_a = false;
                    app.AButton.BackgroundColor = [0.94 0.94 0.94];
                case 'e'
                    app.key_e = false;
                    app.EButton.BackgroundColor = [0.94 0.94 0.94];
                case 'q'
                    app.key_q = false;
                    app.QButton.BackgroundColor = [0.94 0.94 0.94];
            end
        end

        % Value changed function: SpindlemotorSlider
        function spindle_change(app, event)
            speed = sprintf("M3 S%d", round(app.SpindlemotorSlider.Value));
            writeline(app.pico, speed);
            speed_two = sprintf("spin %d", round(app.SpindlemotorSlider.Value));
            writeline(app.pico, speed_two);
            disp(speed_two);
            disp(speed);
        end

        % Value changed function: StepmodeDropDown
        function step_size(app, event)
            mode = sprintf("mode %s", app.StepmodeDropDown.Value);
            disp(mode);
            writeline(app.pico, mode);
        end

        % Value changed function: ModesDropDown
        function mode_selection(app, event)
            mode = app.ModesDropDown.Value;
            switch mode
                case 'manual control'
                    writeline(app.pico, "Manual");
                case 'auto mode'
                    writeline(app.pico, "Auto");
                case 'text control'
                    writeline(app.pico, "m"); % exit manual mode
                    writeline(app.pico, "default");
            end
        end

        % Button pushed function: GcodefileforautoButton
        function gcode_file(app, event)
            if app.ModesDropDown.Value ~= "auto mode"
                disp("Switch to auto mode first");
                return;
            end

            [file, path] = uigetfile({'*.nc;*.gcode;*.txt'});
            
            if file == 0
                return;
            end
            
            fid = fopen(fullfile(path, file), 'r');
            
            writeline(app.pico, "auto"); % switch mode
             
            while ~feof(fid)
                line = strtrim(fgetl(fid));
                
                if isempty(line) || startsWith(line, '(') || startsWith(line, ';')
                    continue;
                end
                
                writeline(app.pico, line);
                pause(0.05);
            end
            
            fclose(fid);
            
            writeline(app.pico, "END");
        end

        % Value changed function: TextcontrolEditField
        function send_text_commands(app, event)
            if app.ModesDropDown.Value ~= "text control"
                app.TextcontrolEditField.Value = "Switch to text control";
                return;
            end
            command = app.TextcontrolEditField.Value;
            writeline(app.pico, command);
            app.TextcontrolEditField.Value = ""; % resets text box
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @key_press, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @key_release, true);
            app.WButton = uibutton(app.UIFigure, 'push');
            app.WButton.ButtonPushedFcn = createCallbackFcn(app, @positive_move_y, true);
            app.WButton.Text = 'W';
            app.SButton = uibutton(app.UIFigure, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @negative_move_y, true);
            app.SButton.Text = 'S';
            app.DButton = uibutton(app.UIFigure, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @positive_move_x, true);
            app.DButton.Text = 'D';
            app.EButton = uibutton(app.UIFigure, 'push');
            app.EButton.ButtonPushedFcn = createCallbackFcn(app, @positive_move_z, true);
            app.EButton.Text = 'E';
            app.AButton = uibutton(app.UIFigure, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @negative_move_x, true);
            app.AButton.Text = 'A';
            app.QButton = uibutton(app.UIFigure, 'push');
            app.QButton.ButtonPushedFcn = createCallbackFcn(app, @negative_move_z, true);
            app.QButton.Text = 'Q';
            app.KeycontrolsforxyzmotorsLabel = uilabel(app.UIFigure);
            app.KeycontrolsforxyzmotorsLabel.Text = 'Key controls for x, y, z motors';
            app.SpindlemotorSliderLabel = uilabel(app.UIFigure);
            app.SpindlemotorSliderLabel.Text = 'Spindle motor ';
            app.SpindlemotorSlider = uislider(app.UIFigure);
            app.SpindlemotorSlider.ValueChangedFcn = createCallbackFcn(app, @spindle_change, true);
            app.StepmodeDropDownLabel = uilabel(app.UIFigure);
            app.StepmodeDropDownLabel.Text = 'Step mode';
            app.StepmodeDropDown = uidropdown(app.UIFigure);
            app.StepmodeDropDown.Items = {'1 ', '2', '3', '4', '5', '6'};
            app.StepmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @step_size, true);
            app.StepmodeDropDown.Value = '1 ';
            app.ManualmodecontrolLabel = uilabel(app.UIFigure);
            app.ManualmodecontrolLabel.Text = 'Manual mode control';
            app.ModesDropDownLabel = uilabel(app.UIFigure);
            app.ModesDropDownLabel.Text = 'Modes';
            app.ModesDropDown = uidropdown(app.UIFigure);
            app.ModesDropDown.Items = {'Manual control', 'auto mode', 'text control'};
            app.ModesDropDown.ValueChangedFcn = createCallbackFcn(app, @mode_selection, true);
            app.ModesDropDown.Value = 'Manual control';
            app.GcodefileforautoButton = uibutton(app.UIFigure, 'push');
            app.GcodefileforautoButton.ButtonPushedFcn = createCallbackFcn(app, @gcode_file, true);
            app.GcodefileforautoButton.Text = 'G-code file for auto';
            app.TextcontrolEditFieldLabel = uilabel(app.UIFigure);
            app.TextcontrolEditFieldLabel.Text = 'Text control';
            app.TextcontrolEditField = uieditfield(app.UIFigure, 'text');
            app.TextcontrolEditField.ValueChangedFcn = createCallbackFcn(app, @send_text_commands, true);
            app.uploadagcodefileLabel = uilabel(app.UIFigure);
            app.uploadagcodefileLabel.Text = 'upload a g-code file';
            app.TestLabel = uilabel(app.UIFigure);
            app.TestLabel.Text = 'Test';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CNC_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @pico_connection)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end